classdef OCT2TIFF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadfromFolderButton       matlab.ui.control.Button
        SelectfilesorfoldersLabel  matlab.ui.control.Label
        FilesListBoxLabel          matlab.ui.control.Label
        FilesListBox               matlab.ui.control.ListBox
        StatusLabel                matlab.ui.control.Label
        SearchallsubfoldersrecursiveCheckBox  matlab.ui.control.CheckBox
        LoadFilesButton            matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        FrameLabel                 matlab.ui.control.Label
    end


    methods (Access = private)
    
        function saveImages(app, handleOrCell, path)
            
            %   SAVEIMAGES  (Corrects and) Saves OCT images to path.
            %   saveImages(handle, path) First corrects images using
            %   getCorrectedOCTImages and then saves them to specified path
            %   with increasing numbers as filename.
            %   saveImages(imgs, path) Saves images in cell array imgs to
            %   path.
            %
            %   See also getCorrectedOCTImages
            
            if iscell(handleOrCell)
                % assuming that you just want to save whatever is in the
                % cell
                correctedImages = handleOrCell;
            else
                % assuming input is handle and should images should be
                % corrected first
                app.StatusLabel.Text = ['Converting images of ',path];
                correctedImages = OCTPATools.getCorrectedOCTImages(handleOrCell);
            end
            
            if ~exist(path)
                mkdir(path);
            end
            
            n = length(correctedImages);
            for i=1:n
                app.StatusLabel.Text = ['Extracting to ', path];
                app.FrameLabel.Text = ['Frame ', num2str(i), '/', num2str(n)];
                imwrite(correctedImages{i}, [path, '/' , num2str(i), '.tif']); 
                                
            
            end
        end
        
    end

    methods (Access = private)

        % Button pushed function: LoadfromFolderButton
        function LoadfromFolderButtonPushed(app, event)
           
            
            path = uigetdir;
            if path ~= 0
                app.FilesListBox.Items = {};
                app.FilesListBox.ItemsData;
                if app.SearchallsubfoldersrecursiveCheckBox.Value
                    filePaths = dirrec(path);
                else
                    files = dir(path);
                    files = {files.name};
                    for i = 1:length(files)
                        filePaths{i} = fullfile(path, files{i});
                    end
                end
                
                n = length(filePaths);
                removeIdx = [];
                for i = 1:n
                    if ~endsWith(filePaths{i}, '.oct')
                        removeIdx = [removeIdx, i];
                    end
                end
                filePaths(removeIdx) = [];
                
                folderPaths = cell(1,length(filePaths));
                fileNames = cell(1,length(filePaths));
                ext = cell(1,length(filePaths));
                for i = 1:length(filePaths)
                    [folderPaths{i}, fileNames{i}, ext{i}] = fileparts(filePaths{i});
                end
                
                app.FilesListBox.Items = fileNames;
                for i = 1:length(folderPaths)
                    app.FilesListBox.ItemsData{i} = fullfile(folderPaths{i}, [fileNames{i}, ext{i}]);
                end
            end
            
            
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
            value = app.FilesListBox.Value;
            
        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            
            
            [files,path] = uigetfile('*',...
                'Select files or folders', ...
                'MultiSelect', 'on');
            if ~iscell(files)
                if files == 0
                    return;
                else
                    files = {files};
                end
            end
            app.FilesListBox.Items = {};
            app.FilesListBox.ItemsData;
            app.FilesListBox.Items = files;
            for i = 1:length(files)
                app.FilesListBox.ItemsData{i} = fullfile(path, files{i});
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            filePaths = app.FilesListBox.ItemsData;
            for i = 1:length(filePaths)
                app.FilesListBox.Value = app.FilesListBox.ItemsData{i};
                [folderPath,filename,~] = fileparts(filePaths{i});
                
                tifFolderPath = fullfile(folderPath, filename);
                if ~exist(tifFolderPath, 'dir')
                    disp(filePaths{i});
                    mkdir(tifFolderPath);
                end
                    % open image
                    app.StatusLabel.Text = ['Reading ', filename];
                    handle = OCTFileOpen(filePaths{i});
                    saveImages(app, handle, tifFolderPath);
                
            end
            
            app.StatusLabel.Text = 'Done';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadfromFolderButton = uibutton(app.UIFigure, 'push');
            app.LoadfromFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromFolderButtonPushed, true);
            app.LoadfromFolderButton.Text = 'Load from Folder...';
            app.SelectfilesorfoldersLabel = uilabel(app.UIFigure);
            app.SelectfilesorfoldersLabel.Text = 'Select file(s) or folder(s)';
            app.FilesListBoxLabel = uilabel(app.UIFigure);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.UIFigure);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.SearchallsubfoldersrecursiveCheckBox = uicheckbox(app.UIFigure);
            app.SearchallsubfoldersrecursiveCheckBox.Text = 'Search all subfolders (recursive)';
            app.LoadFilesButton = uibutton(app.UIFigure, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files...';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OCT2TIFF

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end